classdef motor_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AutorSabauRaduBogdanLabel       matlab.ui.control.Label
        RunMotorButton                  matlab.ui.control.Button
        FOCALUNUIMOTORBRUSHLESSDCLabel  matlab.ui.control.Label
        SimulationControls              simulink.ui.control.SimulationControls
        ReferintaRPMEditField           matlab.ui.control.NumericEditField
        ReferintaRPMEditFieldLabel      matlab.ui.control.Label
        EditField4                      matlab.ui.control.NumericEditField
        EditField4Label                 matlab.ui.control.Label
        EditField3                      matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        EditField2                      matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        TimeScope6                      matlab.ui.scope.TimeScope
        TimeScope5                      matlab.ui.scope.TimeScope
        TimeScope4                      matlab.ui.scope.TimeScope
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunMotorButton
        function run_motor_fcn(app, event)
            ref_speed = app.ReferintaRPMEditField.Value;
            pw = app.EditField3.Value;
            iw = app.EditField2.Value;
            pq = app.EditField.Value;
            pd = app.EditField4.Value;
            
            y = zeros(4, 1000);
            
            command = strcat('sed -i "s/ref_speed =.*/ref_speed = ',num2str(ref_speed),';/" /home/radu/workspace/licenta/SOFTWARE/no-OS/projects/PID_app/src/examples/ext_loop/ext_loop_example.c');
            [status,cmdout] = system(command);
            
            command = strcat('sed -i "s/kp_speed =.*/kp_speed = ',num2str(pw),';/" /home/radu/workspace/licenta/SOFTWARE/no-OS/projects/PID_app/src/examples/ext_loop/ext_loop_example.c');
            [status,cmdout] = system(command);
            
            command = strcat('sed -i "s/ki_speed =.*/ki_speed = ',num2str(iw),';/" /home/radu/workspace/licenta/SOFTWARE/no-OS/projects/PID_app/src/examples/ext_loop/ext_loop_example.c');
            [status,cmdout] = system(command);
            
            command = strcat('sed -i "s/kp_torque =.*/kp_torque = ',num2str(pq),';/" /home/radu/workspace/licenta/SOFTWARE/no-OS/projects/PID_app/src/examples/ext_loop/ext_loop_example.c');
            [status,cmdout] = system(command);
            
            command = strcat('sed -i "s/kp_flux =.*/kp_flux = ',num2str(pd),';/" /home/radu/workspace/licenta/SOFTWARE/no-OS/projects/PID_app/src/examples/ext_loop/ext_loop_example.c');
            [status,cmdout] = system(command);
            
            cd '/home/radu/workspace/licenta/SOFTWARE/no-OS/projects/PID_app/';
            
            command = 'stty -F /dev/ttyACM0 1000000';
            [status, cmdout] = system(command);
            command = 'cat /dev/ttyACM0 > speed_plot.txt & make PLATFORM=stm32 run';
            [status, cmdout] = system(command);
            
            pause(10);

            fid = fopen('speed_plot.txt','rt');
            C = textscan(fid, '%f%f%f%f');
            fclose(fid);
            y=cell2mat(C);
            
            plot(app.UIAxes2,y(:,1:1),y(:,2:2));
            plot(app.UIAxes,y(:,1:1),y(:,3:3).*0.001134634);
            plot(app.UIAxes3,y(:,1:1),y(:,4:4).*0.001134634);
            
            command = 'rm speed_plot.txt';
            [status, cmdout] = system(command);

            command = 'pkill -f "cat /dev/ttyACM0"';
            [status, cmdout] = system(command);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cuplu Montaj HW')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Viteza Montaj HW')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Flux Montaj HW')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.TimeScope4 = uitimescope(app.UIFigure);
            app.TimeScope4.LegendNames = {'Simulation Torque'};
            app.TimeScope4.Title = 'Cuplu Simulare';
            app.TimeScope4.YLabel = 'Amplitude';
            app.TimeScope4.XLimits = [0 1];
            app.TimeScope4.XTimeSpan = 1;
            app.TimeScope5 = uitimescope(app.UIFigure);
            app.TimeScope5.LegendNames = {'Simulation Speed'};
            app.TimeScope5.Title = 'Viteza Simulare';
            app.TimeScope5.YLabel = 'Amplitude';
            app.TimeScope5.XLimits = [0 1];
            app.TimeScope5.XTimeSpan = 1;
            app.TimeScope6 = uitimescope(app.UIFigure);
            app.TimeScope6.LegendNames = {'Simulation Flux'};
            app.TimeScope6.Title = 'Flux Simulare';
            app.TimeScope6.YLabel = 'Amplitude';
            app.TimeScope6.XLimits = [0 1];
            app.TimeScope6.XTimeSpan = 1;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'P-Cuplu';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'I-Viteza';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Value = 1;
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'P-Viteza';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Value = 1;
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'P-Flux';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.Value = 1;
            app.ReferintaRPMEditFieldLabel = uilabel(app.UIFigure);
            app.ReferintaRPMEditFieldLabel.Text = 'Referinta [RPM]';
            app.ReferintaRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferintaRPMEditField.Value = 1000;
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.FOCALUNUIMOTORBRUSHLESSDCLabel = uilabel(app.UIFigure);
            app.FOCALUNUIMOTORBRUSHLESSDCLabel.Text = 'FOC AL UNUI MOTOR BRUSHLESS DC';
            app.RunMotorButton = uibutton(app.UIFigure, 'push');
            app.RunMotorButton.ButtonPushedFcn = createCallbackFcn(app, @run_motor_fcn, true);
            app.RunMotorButton.Text = 'Run Motor';
            app.AutorSabauRaduBogdanLabel = uilabel(app.UIFigure);
            app.AutorSabauRaduBogdanLabel.Text = 'Autor : Sabau Radu Bogdan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.EditField2, 'Value', app.Simulation.TunableVariables, ['I_speed:' app.Simulation.ModelName]);
            bind(app.EditField4, 'Value', app.Simulation.TunableVariables, ['P_flux:' app.Simulation.ModelName]);
            bind(app.EditField3, 'Value', app.Simulation.TunableVariables, ['P_speed:' app.Simulation.ModelName]);
            bind(app.EditField, 'Value', app.Simulation.TunableVariables, ['P_torque:' app.Simulation.ModelName]);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Sensor/PS-Simulink Converter:1'], app.TimeScope5);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/STM32_F767ZI/Unit Delay1:1'], app.TimeScope6);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/STM32_F767ZI/Unit Delay2:1'], app.TimeScope4);
            bind(app.ReferintaRPMEditField, 'Value', app.Simulation.TunableVariables, ['w:' app.Simulation.ModelName]);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor_app

            % Associate the Simulink Model
            app.Simulation = simulation('control_vectorial_bldc');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end